classdef VIStudio_AnomalyDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModelLoadedLamp           matlab.ui.control.Lamp
        ModelLoadedLampLabel      matlab.ui.control.Label
        DataSourceEditField       matlab.ui.control.EditField
        DataSourceEditFieldLabel  matlab.ui.control.Label
        ModelDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        StateChangeSwitch         matlab.ui.control.Switch
        ModelReloadButton         matlab.ui.control.Button
        Inference_Panel           matlab.ui.container.Panel
        InferenceImgUIAxes        matlab.ui.control.UIAxes
        ToolbarPanel              matlab.ui.container.Panel
        LoadImageButton           matlab.ui.control.Button
        AnomalyDetectionButton    matlab.ui.control.Button
        ExportModelButton         matlab.ui.control.Button
        TrainingOptionsButton     matlab.ui.control.StateButton
        StartTrainButton          matlab.ui.control.Button
        DataImportButton          matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        AnomalyDetectionLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)

        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        trainingOpts
        splitRatio

        rawDataSrc
        normalDataSrc
        abnormalDataSrc
        validationDataSrc
        normalValDataSrc
        abnormalValDataSrc
        

        AnomalyMdl % Description
        detectorType

        trainedDetector

        inferenceImg
        inferenceResult
        
        explainMap

    end
    
    methods (Access = private)

         function gatherTrainingOptionsParameters(app)

            % Check if TrainingOptionApp is empty 
            if ~isempty(app.TrainingOptionApp)
                
                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    ValidationData = [],...%app.trainingDict{"ValidationData"},...
                    ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"}...
                    );
            else
                app.trainingOpts = trainingOptions("adam", ...
                    InitialLearnRate=0.01,...
                    MaxEpochs=1,...
                    MiniBatchSize=2,...
                    ValidationData={app.normalValDataSrc, app.abnormalValDataSrc}, ...
                    ValidationFrequency=5, ...
                    ExecutionEnvironment="gpu",...
                    Plots="training-progress", ...
                    Verbose=false);
            end
         end
        
        function  imgOut = preprocessDsImg(app,filename)
            img = imread(filename);
            imgOut = imresize(img, [125,125]); % Temp size
        end

       function chkModelLoaded(app)
            if ~isempty(app.AnomalyMdl)
                app.ModelLoadedLamp.Color = [0.39,0.83,0.07];
            end
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.detectorType = app.ModelDropDown.Value;
            

            switch app.detectorType
                case "FCDD"
                    backbone = pretrainedEncoderNetwork("resnet18",3);
                    app.AnomalyMdl = fcddAnomalyDetector(backbone);
                case "PatchCore"
                    app.AnomalyMdl = patchCoreAnomalyDetector;
                case "User Trained Model"
                    [file, path] = uigetfile('*.mat');
                    if isscalar(file) || isscalar(path)
                        disp('User cancel import')
                        return
                    end
                
                    % 讀取使用者已訓練模型
                    load(fullfile(path, file))
                    app.trainedDetector = model;
                    modelType = class(app.trainedDetector);
                    if contains(modelType, 'fcdd')
                        app.detectorType = "FCDD";
                    else
                        app.detectorType = "PatchCore";
                    end

            end
        end

        % Button pushed function: DataImportButton
        function DataImportButtonPushed(app, event)
            folderpath = uigetdir;


            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                   progressbar = uiprogressdlg(app.UIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.rawDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");
                app.DataSourceEditField.Value = folderpath;
                delete(progressbar)
            end

            labelNum = numel(app.rawDataSrc.Labels);
            if labelNum < 2
                disp('Only one class')
            else
                [app.normalDataSrc, app.validationDataSrc, app.abnormalDataSrc] = splitAnomalyData(app.rawDataSrc,"Anomaly");
            end

            normIdx = app.validationDataSrc.Labels == "Normal";
             app.normalValDataSrc = subset(app.validationDataSrc, normIdx);
             app.abnormalValDataSrc = subset(app.validationDataSrc, ~normIdx);
            

        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            gatherTrainingOptionsParameters(app)

            trainingProgressBar = uiprogressdlg(app.UIFigure,...
                Message="Network Training...",...
                Title='Train network',...
                Indeterminate='on');
            switch app.detectorType
                case "FCDD"
                    app.trainedDetector = trainFCDDAnomalyDetector(app.normalDataSrc, app.abnormalDataSrc, app.AnomalyMdl, app.trainingOpts);
                case "PatchCore"
                    app.trainedDetector = trainPatchCoreAnomalyDetector(app.normalDataSrc, app.AnomalyMdl, CompressionRatio=0.1);
                case "User Trained Model"
                    % app.trainedDetector = app.AnomalyMdl;
            end
            delete(trainingProgressBar)
        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
            chkModelLoaded(app)
        end

        % Button pushed function: AnomalyDetectionButton
        function AnomalyDetectionButtonPushed(app, event)
            app.explainMap = anomalyMap(app.trainedDetector, app.inferenceImg);
            ExplainMethods = app.ModelDropDown.Value;
             progressbar = uiprogressdlg(app.UIFigure,...
                Message="Model explain using: "+ ExplainMethods ,...
                Title="Model Explain",...
                Indeterminate="on"); 
            app.inferenceResult = anomalyMapOverlay(app.inferenceImg, app.explainMap, Blend="equal");
            imshow(app.inferenceResult, Parent=app.InferenceImgUIAxes)
            delete(progressbar)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             [file, path] = uigetfile('*.*',MultiSelect="off");

            if isscalar(file) || isscalar(path)
                disp('User discard import image')
                return
            end

            app.inferenceImg = preprocessDsImg(app,fullfile(path,file));

            % Visualization
            imshow(app.inferenceImg, Parent=app.InferenceImgUIAxes)

        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            % 匯出訓練好的 Detector
            if ~isempty(app.trainedDetector)
                [file,location] = uiputfile('.*', 'Save trained model', 'trainedModel.mat');
                model = app.trainedDetector;
                save(fullfile(location,file), 'model')
            end
        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_AnomalyTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.AnomalyDetectionLabel = uilabel(app.Panel);
            app.AnomalyDetectionLabel.Text = 'Anomaly Detection';
            app.ToolbarPanel = uipanel(app.UIFigure);
            app.DataImportButton = uibutton(app.ToolbarPanel, 'push');
            app.DataImportButton.ButtonPushedFcn = createCallbackFcn(app, @DataImportButtonPushed, true);
            app.DataImportButton.Icon = 'Import Data.png';
            app.DataImportButton.Text = {'Data'; 'Import'};
            app.StartTrainButton = uibutton(app.ToolbarPanel, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = 'Train.png';
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.TrainingOptionsButton = uibutton(app.ToolbarPanel, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = 'setting.png';
            app.TrainingOptionsButton.Text = {'Training'; 'Options'};
            app.ExportModelButton = uibutton(app.ToolbarPanel, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = 'Export.png';
            app.ExportModelButton.Text = {'Export'; 'Model'};
            app.AnomalyDetectionButton = uibutton(app.ToolbarPanel, 'push');
            app.AnomalyDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AnomalyDetectionButtonPushed, true);
            app.AnomalyDetectionButton.Icon = 'Detect Anomaly.png';
            app.AnomalyDetectionButton.Text = {'Anomaly'; 'Detection'};
            app.LoadImageButton = uibutton(app.ToolbarPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'photo.png';
            app.LoadImageButton.Text = {'Load'; 'Image'};
            app.Inference_Panel = uipanel(app.UIFigure);
            app.InferenceImgUIAxes = uiaxes(app.Inference_Panel);
            title(app.InferenceImgUIAxes, 'Title')
            zlabel(app.InferenceImgUIAxes, 'Z')
            app.ModelReloadButton = uibutton(app.UIFigure, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.StateChangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.StateChangeSwitch.Items = {'Inference', 'Training'};
            app.StateChangeSwitch.Value = 'Inference';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'FCDD', 'PatchCore', 'User Trained Model'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'FCDD';
            app.DataSourceEditFieldLabel = uilabel(app.UIFigure);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.UIFigure, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.ModelLoadedLampLabel = uilabel(app.UIFigure);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_AnomalyDetectionApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end