classdef VIStudio_ClassificationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CatDistributionChart_Panel  matlab.ui.container.Panel
        StateChangeSwitch           matlab.ui.control.Switch
        Inference_Panel             matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        SwitchLabel                 matlab.ui.control.Label
        ExplainButton               matlab.ui.control.Button
        MethodsDropDown             matlab.ui.control.DropDown
        MethodsDropDownLabel        matlab.ui.control.Label
        InferenceImgUIAxes          matlab.ui.control.UIAxes
        ModelReloadButton           matlab.ui.control.Button
        ModelLoadedLamp             matlab.ui.control.Lamp
        ModelLoadedLampLabel        matlab.ui.control.Label
        DataSourceEditField         matlab.ui.control.EditField
        DataSourceEditFieldLabel    matlab.ui.control.Label
        VersionDemo0001Label        matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        ModelDropDownLabel          matlab.ui.control.Label
        ToolBar_Panel               matlab.ui.container.Panel
        TrainingOptionsButton       matlab.ui.control.StateButton
        LoadImageButton             matlab.ui.control.Button
        ModelClassifyButton         matlab.ui.control.Button
        ExportModelButton           matlab.ui.control.Button
        StartTrainButton            matlab.ui.control.Button
        DataImportButton            matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ClassificationLabel         matlab.ui.control.Label
        ConfusionMat_Panel          matlab.ui.container.Panel
    end


    properties (Access = private)
        currFigPosition
    end

    properties (Access = public)
        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];


        trainingOpts

        netInputSz
        preTrainedNetwork
        preTrainedNetwork_classname

        rawDataSrc
        trainingDataSrc
        validationDataSrc

        validSpitRatio = 0.8
        trainClassNum
        trainClassName

        trainedNet

        inferenceImg
        inferenceResult
        inferenceScore
        explainMap
    end

    methods (Access = private)
        %% Get Training Options from Subapp
        % Function: Gather training options from subapp
        % "VIStudio_ClassificationTrainingOption.mlapp

        function gatherTrainingOptionsParameters(app)

            % Check if TrainingOptionApp is empty
            if ~isempty(app.TrainingOptionApp)

                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    ValidationData = [],...%app.trainingDict{"ValidationData"},...
                    ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"},...
                    Metrics = ["accuracy","fscore"]...
                    );
            else
                app.trainingOpts = trainingOptions("adam", ...
                    InitialLearnRate=0.01,...
                    MaxEpochs=5,...
                    MiniBatchSize=64,...
                    ValidationData=app.validationDataSrc, ...
                    ValidationFrequency=5, ...
                    Plots="training-progress", ...
                    Metrics = ["accuracy","fscore"], ...
                    Verbose=false);
            end
        end

        %% Preprocess the Image Data Source
        function imgOut = preprocessDsImg(app,filename)
            img = imread(filename);
            imgOut = imresize(img, app.netInputSz(1:2));
        end

        %% Get Model Required Data Input Size
        function getNetworkInputSize(app)

            net = app.preTrainedNetwork;

            if ~isscalar(net.InputNames)
                errordlg("Networks with multiple inputs are not supported.")
            end
            inputName = net.InputNames{1};
            layer = net.Layers(1);

            % Supported layers list
            supportedLayers = [
                "ImageInputLayer",...
                "Image3DInputLayer",...
                "SequebceInputLayer",...
                "FeatureInputLayer"...
                ];
            if ~contains(class(layer), supportedLayers)
                errordlg("Input layer must be an image, 3-D image, sequence, or feature input layer.")
            end
            app.netInputSz  =layer.InputSize;
        end


        function importTrainingData(app)
        end

        %% Check for Model Loaded
        function ModelLoaded = chkModelLoaded(app)
            ModelLoaded = false;
            if ~isempty(app.preTrainedNetwork)
                ModelLoaded = true;
                app.ModelLoadedLamp.Color = [0.39,0.83,0.07];   % Change for green light
                app.ModelClassifyButton.Enable = true;
                app.StartTrainButton.Enable = true;
            end
        end

        %%  Check for Model Add-On installed
        function isInstalled = chkAddOnInstalled(app, ModelName)
            isInstalled = true;
            if ~isdeployed
                addon = matlab.addons.installedAddons();
                if ~any(strcmpi(addon.Identifier, ModelName))
                    msg = "Model: "+ModelName+" does not found, Please install from Add-On first";
                    uialert(app.UIFigure, msg, 'Model not installed')
                    isInstalled = false;
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.currFigPosition = app.UIFigure.Position(1:2);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.currFigPosition = app.UIFigure.Position(1:2);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.currFigPosition = app.UIFigure.Position(1:2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.TrainingOptionApp)
            delete(app)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(app.TrainingOptionApp)
                ismoved = app.UIFigure.Position(2)~=app.TrainingOptionApp.TrainingOptionUIFigure.Position(2);
                if ismoved
                    app.TrainingOptionApp.PositionSetFcn
                end
            end
        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_ClassificationTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end


        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            % Get training info and model input size
            gatherTrainingOptionsParameters(app)
            getNetworkInputSize(app)

            [layerName,learnableNames] = networkHead(app.preTrainedNetwork);
            net = freezeNetwork(app.preTrainedNetwork, LayerNamesToIgnore=layerName);

            trainingProgressBar = uiprogressdlg(app.UIFigure,...
                Message="Network Training...",...
                Title='Train network',...
                Indeterminate='on');

            app.trainedNet = trainnet(app.trainingDataSrc,...
                net,...
                "crossentropy",...
                app.trainingOpts);


            delete(trainingProgressBar)

            % Validation result
            testOutput = minibatchpredict( app.trainedNet, app.validationDataSrc);
            testOutput = scores2label(testOutput,app.trainClassName);

            trueLabel = app.validationDataSrc.Labels;
            confusionchart(trueLabel, testOutput, Parent=app.ConfusionMat_Panel);

            acc = mean(trueLabel==testOutput)

        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            netWorkType = app.ModelDropDown.Value;

            switch netWorkType
                % User load trained network
                case "CustomTrainedNetwork"
                otherwise
                    if  chkAddOnInstalled(app, netWorkType)
                    else
                        return
                    end

                    progressbar = uiprogressdlg(app.UIFigure,...
                        Message="Loading Model: "+ netWorkType +", Please wait...",...
                        Title="Import Model",...
                        Indeterminate="on");

                    % Get pretrained model
                    if ~isempty(app.trainClassNum)
                    app.preTrainedNetwork=...
                        imagePretrainedNetwork(netWorkType,NumClasses=app.trainClassNum);
                    else
                        [app.preTrainedNetwork, app.preTrainedNetwork_classname]  =...
                        imagePretrainedNetwork(netWorkType,NumClasses=app.trainClassNum);
                    
                    end
                    delete(progressbar)
            end


        end

        % Button pushed function: DataImportButton
        function DataImportButtonPushed(app, event)
            % Import image data
            folderpath = uigetdir;

            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                progressbar = uiprogressdlg(app.UIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.rawDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");
                app.trainClassName = categories(app.rawDataSrc.Labels);
                app.trainClassNum = numel(app.trainClassName);
                app.rawDataSrc.ReadFcn = @(x)preprocessDsImg(app,x);

                % Split data into training and validation
                [app.trainingDataSrc, app.validationDataSrc] =...
                    splitEachLabel(app.rawDataSrc, app.validSpitRatio, 'randomize');

                app.DataSourceEditField.Value = folderpath;

                delete(progressbar)

                % Data distribution visualization
                counts = countlabels(app.rawDataSrc.Labels);
                piechart(categorical(app.rawDataSrc.Labels),...
                    Parent=app.CatDistributionChart_Panel,...
                    Labels=categories(app.rawDataSrc.Labels)+"\newline"+string(counts.Count),...
                    Title="Training Data Distribution")

            end


        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
            if chkModelLoaded(app)
                getNetworkInputSize(app)
            end
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            if ~isempty(app.trainedNet)
                [file,location] = uiputfile('.*', 'Save trained model', 'trainedModel.mat');
                model = app.trainedNet;
                save(fullfile(location,file), 'model')
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile('*.*',MultiSelect="off");

            if isscalar(file) || isscalar(path)
                disp('User discard import image')
                return
            end

            app.inferenceImg = preprocessDsImg(app,fullfile(path,file));

            % Update file path information
            app.DataSourceEditField.Value = fullfile(path,file);

            % Visualization
            imshow(app.inferenceImg, Parent=app.InferenceImgUIAxes)


        end

        % Button pushed function: ModelClassifyButton
        function ModelClassifyButtonPushed(app, event)
            output = predict(app.preTrainedNetwork, single(app.inferenceImg));

            if isempty(app.preTrainedNetwork_classname)
                predictClass = scores2label(output, app.trainClassName);
            else
            predictClass = scores2label(output, app.preTrainedNetwork_classname);
            end
            app.InferenceImgUIAxes.Title.String = string(predictClass);

            app.inferenceResult = predictClass;
            app.inferenceScore = output;
            app.Switch.Value = 'On';
        end

        % Button pushed function: ExplainButton
        function ExplainButtonPushed(app, event)

            ExplainMethods = app.MethodsDropDown.Value;

            progressbar = uiprogressdlg(app.UIFigure,...
                Message="Model explain using: "+ ExplainMethods ,...
                Title="Model Explain",...
                Indeterminate="on");
            switch ExplainMethods
                case 'GradCam'
                    app.explainMap = gradCAM(app.preTrainedNetwork, app.inferenceImg, app.inferenceResult);
                case 'LIME'
                    [~, channel] = max(app.inferenceScore);
                    app.explainMap = imageLIME(app.preTrainedNetwork, app.inferenceImg, channel);
            end

            delete(progressbar)

            hold(app.InferenceImgUIAxes,'on');
            imagesc(app.InferenceImgUIAxes, app.explainMap, AlphaData=0.3);
            colormap(app.InferenceImgUIAxes, 'jet')
            hold(app.InferenceImgUIAxes,'off');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    ExplainButtonPushed(app)
                case 'Off'
                    hold(app.InferenceImgUIAxes,"off")
                    imshow(app.inferenceImg, Parent=app.InferenceImgUIAxes)
            end
        end

        % Value changed function: StateChangeSwitch
        function StateChangeSwitchValueChanged(app, event)
            session = app.StateChangeSwitch.Value;
            switch session
                case 'Inference'
                    app.Inference_Panel.Visible = "on";
                    app.Inference_Panel.Enable = "on";
                    app.CatDistributionChart_Panel.Visible = "off";
                    app.CatDistributionChart_Panel.Enable = "off";
                    app.ConfusionMat_Panel.Visible = "off";
                    app.ConfusionMat_Panel.Enable = "off";
                case 'Training'
                    app.Inference_Panel.Visible = "off";
                    app.Inference_Panel.Enable = "off";
                    app.CatDistributionChart_Panel.Visible = "on";
                    app.CatDistributionChart_Panel.Enable = "on";
                    app.ConfusionMat_Panel.Visible = "on";
                    app.ConfusionMat_Panel.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.ConfusionMat_Panel = uipanel(app.UIFigure);
            app.ConfusionMat_Panel.Enable = 'off';
            app.ConfusionMat_Panel.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.ClassificationLabel = uilabel(app.Panel);
            app.ClassificationLabel.Text = 'Classification';
            app.ToolBar_Panel = uipanel(app.UIFigure);
            app.DataImportButton = uibutton(app.ToolBar_Panel, 'push');
            app.DataImportButton.ButtonPushedFcn = createCallbackFcn(app, @DataImportButtonPushed, true);
            app.DataImportButton.Icon = 'Import Data.png';
            app.DataImportButton.Text = {'Data'; 'Import'};
            app.StartTrainButton = uibutton(app.ToolBar_Panel, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = 'Train.png';
            app.StartTrainButton.Enable = 'off';
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.ExportModelButton = uibutton(app.ToolBar_Panel, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = 'Export.png';
            app.ExportModelButton.Text = {'Export'; 'Model'};
            app.ModelClassifyButton = uibutton(app.ToolBar_Panel, 'push');
            app.ModelClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModelClassifyButtonPushed, true);
            app.ModelClassifyButton.Icon = 'Inference.png';
            app.ModelClassifyButton.Enable = 'off';
            app.ModelClassifyButton.Text = {'Model'; 'Classify'};
            app.LoadImageButton = uibutton(app.ToolBar_Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'photo.png';
            app.LoadImageButton.Text = {'Load'; 'Image'};
            app.TrainingOptionsButton = uibutton(app.ToolBar_Panel, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = 'setting.png';
            app.TrainingOptionsButton.Text = {'Training'; 'Options'};
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'resnet18', 'resnet50', 'xception', 'inceptionv3', 'mobilenetv2', 'densenet201', 'CustomTrainedNetwork'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'resnet18';
            app.VersionDemo0001Label = uilabel(app.UIFigure);
            app.VersionDemo0001Label.Text = 'Version: Demo 0.0.01';
            app.DataSourceEditFieldLabel = uilabel(app.UIFigure);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.UIFigure, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.ModelLoadedLampLabel = uilabel(app.UIFigure);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.UIFigure);
            app.ModelReloadButton = uibutton(app.UIFigure, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.Inference_Panel = uipanel(app.UIFigure);
            app.InferenceImgUIAxes = uiaxes(app.Inference_Panel);
            title(app.InferenceImgUIAxes, 'Title')
            zlabel(app.InferenceImgUIAxes, 'Z')
            app.MethodsDropDownLabel = uilabel(app.Inference_Panel);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.Inference_Panel);
            app.MethodsDropDown.Items = {'GradCam', 'LIME'};
            app.MethodsDropDown.Value = 'GradCam';
            app.ExplainButton = uibutton(app.Inference_Panel, 'push');
            app.ExplainButton.ButtonPushedFcn = createCallbackFcn(app, @ExplainButtonPushed, true);
            app.ExplainButton.Text = 'Explain';
            app.SwitchLabel = uilabel(app.Inference_Panel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Inference_Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.StateChangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.StateChangeSwitch.Items = {'Inference', 'Training'};
            app.StateChangeSwitch.ValueChangedFcn = createCallbackFcn(app, @StateChangeSwitchValueChanged, true);
            app.StateChangeSwitch.Value = 'Inference';
            app.CatDistributionChart_Panel = uipanel(app.UIFigure);
            app.CatDistributionChart_Panel.Enable = 'off';
            app.CatDistributionChart_Panel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ClassificationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end