classdef VIStudio_ObjectDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModelLoadedLamp           matlab.ui.control.Lamp
        ModelLoadedLampLabel      matlab.ui.control.Label
        DataSourceEditField       matlab.ui.control.EditField
        DataSourceEditFieldLabel  matlab.ui.control.Label
        ModelDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        StateChangeSwitch         matlab.ui.control.Switch
        ModelReloadButton         matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ObjectDetectionButton     matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        LoadImageButton           matlab.ui.control.Button
        LoadgTruthButton          matlab.ui.control.Button
        ExportModelButton         matlab.ui.control.Button
        TrainingOptionsButton     matlab.ui.control.StateButton
        StartTrainButton          matlab.ui.control.Button
        DataImportButton          matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        ObjectDetectionLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        trainingOpts

        objDetectionMdl % Description


        trainingImgDataSrc
        trainingBoxDataSrc
        trainingCombDataSrc

        imgResizeSz = [640, 640]

        gTruth
        gTruthLabel

        trainedDetector
        inferenceImg
        inferenceBboxes
        inferenceScores
        inferenceResult
    end
    
    methods (Access = private)
        
        function results = createTrainingSrc(app)
            if isempty(app.gTruth)
                return
            end

            % gTruth 第一欄為訓練圖檔位置，第二欄開始為 box label 資訊
            app.trainingImgDataSrc = imageDatastore(app.gTruth.Files, ReadFcn=@(x)preprocessImg(app, x));
            app.trainingBoxDataSrc = boxLabelDatastore(app.gTruth(:,2:end));

            app.trainingCombDataSrc = combine(app.trainingImgDataSrc,...
                app.trainingBoxDataSrc);


        end
        
        function outputImg = preprocessImg(app, files)
            img = imread(files);
            outputImg = imresize(img, app.imgResizeSz);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            ModelType = app.ModelDropDown.Value;

            progressBar = uiprogressdlg(app.UIFigure,...
                Message="Loading Detector Model: "+ ModelType,...
                Title="Loading Model",...
                Indeterminate="on");
            switch ModelType
                case 'YOLOX-nano'

                case 'YOLOX-tiny'
                    detector = yoloxObjectDetector("tiny-coco");
                case 'YOLOX-small'
                    detector = yoloxObjectDetector("small-coco");
                case 'YOLOX-medium'
                case 'YOLOX-large'
            end

            app.objDetectionMdl = detector;


           if ~isempty(app.objDetectionMdl)
               app.ModelLoadedLamp.Color = [0.47,0.67,0.19];
           end
            delete(progressBar)
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
            if isempty(app.objDetectionMdl)
                return
            elseif ~isempty(app.trainedDetector) && ~isempty(app.inferenceImg)
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.trainedDetector, app.inferenceImg);
            else
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.objDetectionMdl, app.inferenceImg);
            end

            detectedImg = insertObjectAnnotation(app.inferenceImg,"Rectangle",app.inferenceBboxes,app.inferenceResult);

            imshow(detectedImg, Parent=app.UIAxes)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile('*.*',MultiSelect='off');
            if isscalar(file) || isscalar(path)
                disp('User discard import file')
                return
            end
            app.inferenceImg = imread(fullfile(path,file));

            imshow(app.inferenceImg, Parent=app.UIAxes)
        end

        % Button pushed function: LoadgTruthButton
        function LoadgTruthButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', MultiSelect='off');
             if isscalar(file) || isscalar(path)
                disp('User discard import file')
                return
            end

            loadedMat = load(fullfile(path, file));
            app.gTruth = loadedMat.exportROI; % gTruth variable from VIStudio_ImageLabeler

            chkGroundTruth(app)
        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            if isempty(app.objDetectionMdl)
                di('No Model Loaded')
                return
            end

            [app.trainedDetector, info] = trainYOLOXObjectDetector(...
                app.trainingCombDataSrc,... 
                app.objDetectionMdl, ...
                app.trainingOpts,...
                FreezeSubNetwork="none");

        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            value = app.TrainingOptionsButton.Value;
             OptionOnOff = app.TrainingOptionsButton.Value;
            
            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_ObjectDetectionTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ObjectDetectionLabel = uilabel(app.Panel);
            app.ObjectDetectionLabel.Text = 'Object Detection';
            app.Panel_2 = uipanel(app.UIFigure);
            app.DataImportButton = uibutton(app.Panel_2, 'push');
            app.DataImportButton.Icon = 'Import Data.png';
            app.DataImportButton.Text = {'Data'; 'Import'};
            app.StartTrainButton = uibutton(app.Panel_2, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = 'Train.png';
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.TrainingOptionsButton = uibutton(app.Panel_2, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = 'setting.png';
            app.TrainingOptionsButton.Text = {'Training '; 'Options'};
            app.ExportModelButton = uibutton(app.Panel_2, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = 'Export.png';
            app.ExportModelButton.Text = {'Export'; 'Model'};
            app.LoadgTruthButton = uibutton(app.Panel_2, 'push');
            app.LoadgTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadgTruthButtonPushed, true);
            app.LoadgTruthButton.Icon = 'groundTruth.png';
            app.LoadgTruthButton.Text = {'Load'; 'gTruth'};
            app.LoadImageButton = uibutton(app.Panel_2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'photo.png';
            app.LoadImageButton.Text = {'Load'; 'Image'};
            app.ObjectDetectionButton = uibutton(app.UIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Icon = 'eye.png';
            app.ObjectDetectionButton.Text = {'Object'; 'Detection'};
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.ModelReloadButton = uibutton(app.UIFigure, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.StateChangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.StateChangeSwitch.Items = {'Inference', 'Training'};
            app.StateChangeSwitch.Value = 'Inference';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'YOLOX-tiny', 'YOLOX-small', 'CustomTrainedNetwork'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'YOLOX-tiny';
            app.DataSourceEditFieldLabel = uilabel(app.UIFigure);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.UIFigure, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.ModelLoadedLampLabel = uilabel(app.UIFigure);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ObjectDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end